classdef grafico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GraficoUIFigure       matlab.ui.Figure
        GridLayout2           matlab.ui.container.GridLayout
        UIAxes                matlab.ui.control.UIAxes
        PulisciassiButton     matlab.ui.control.Button
        Switch                matlab.ui.control.Switch
        SOVRAPPOSIZIONELabel  matlab.ui.control.Label
        FiltraButton_2        matlab.ui.control.Button
        Tree                  matlab.ui.container.Tree
        TempodiavvioLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        overlay_n=[];
        overlay_l=[];
        collection
        filterApp
    end
    
    properties (Access = public)
        markerFlt
        minTmFlt
        maxTmFlt
    end
    
    
    methods (Access = private)
        
        function overlayed(app) % takes in input an array with selected node a prints the graph
            %EVITARE DUPLICAZIONI
            app.overlay_l
            app.overlay_n
            dim=size(app.overlay_l)
            lght=max(app.overlay_l)
            for i=1:dim
                switch app.overlay_n(i)
                case 'Inverter right'
                
                case 'data1_r'
                    
                case 'data2_r'
                
                case 'Inverter left'
                    
                case 'data1_l'
                    
                case 'data2_l'
                    
                case 'Bms_hv'
                    
                case 'temperature'
                    
                case 'max_thv'
                    
                case 'min_thv'
                    
                case 'average_thv'
                    
                case 'voltage'
                    
                case 'max_vhv'
                    
                case 'min_vhv'
                    
                case 'average_vhv'
                    
                case 'current'
                    
                case 'current_c'
                    
                case 'pow'
                    
                case 'Bms_lv'
                    
                case 'voltage_lv'
                    
                case 'temperature_lv'
                    
                case 'Imu_gyro'
                    
                case 'x_g'
                
                case 'y_g'
                    
                case 'z_g'
                    
                case 'Imu_accel'
                    
                case 'x_a'
                    
                case 'y_a'
                    
                case 'z_a'
                    
                case 'Front_wheels_encoder'
                    
                case 'speed'
                
                case 'speedms'
                    
                case 'Steering_wheel_encoder'
                    
                case 'Distance'
                    
                case 'meters'
                    
                case 'rotation'
                    
                case 'angle' 
                   
                case 'clock_period'
                    
                case "Throttle"
                    plot(app.UIAxes,linspace(1,lght,app.overlay_l(i)),app.tblThrottle{:,5})
                    hold(app.UIAxes,"on")
                case "Brake" 
                    plot(app.UIAxes,linspace(1,lght,app.overlay_l(i)),app.tblBrake{:,5})
                    hold(app.UIAxes,"on")
                end
            end
            legend(app.UIAxes,app.overlay_n);
        end
        
        function filtra(app)
            uialert(app.GraficoUIFigure,'Filtro impostato','Yee')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, origin, collection_db, fileName, save_v, sessionName)
            app.CallingApp = mainapp;
            tabledata=readtable('output.csv','PreserveVariableNames',true);
            if origin=="local"
                load(fileName,'-mat','collection_db')
                app.collection=collection_db;
            else
                app.collection=collection_db;
                d=size(collection_db);
                if save_v==1
                    save(sessionName,'collection_db','sessionName','d');
                end
            end
            dim=size(collection_db);

            for i=1:dim(1,1)
                var=char(tabledata{i,1});
                route=char(tabledata{i,2});
                if isempty(tabledata{i,3})
                    str=strcat(var,"=[];",var,"=[",var,",[collection_db(i).",route,"]];");
                    try
                       eval(str);
                    catch ME
                       disp(ME); 
                    end 
                else
                    str=strcat(var,"_tmp=[];",var,"_tmp=[",var,"_tmp,[collection_db(i).",route,"]];");
                    try
                        eval(str);
                    catch ME
                       disp(ME); 
                    end
                    str=strcat("size(",var,"_tmp);");
                    dim2=eval(str);
                    str=strcat(var,"=[];");
                    eval(str);
                    appendix=char(tabledata{i,3});
                    for j=1:dim2(1,2)
                        str=strcat(var,"=[",var,",[",var,"_tmp(j).",appendix,"]];");
                        try
                           eval(str);
                        catch ME
                           disp(ME); 
                        end 
                    end
                end
            end
            firstLayer=[];
            secondLayer=[];
            thirdLayer=[];
            fourthLayer=[];
            dim2=size(tabledata);
            for i=1:dim2(1,1)
                var=char(tabledata{i,1});
                splitVar=split(var,'__');
                strDim=size(splitVar);
                %Annidato fino a un massimo di 4 livelli
                %TODO implementare con colonne di un singolo vettore
                %(matrice)
                
                for k=1:strDim(1,1)
                    if k==1
                        if ~any(strcmp(firstLayer,char(splitVar(k))))
                            firstLayer=[firstLayer,splitVar(k)];
                            if k==strDim(1,1)
                                str=strcat(char(splitVar(k)),"= uitreenode(app.Tree);",char(splitVar(k)),".Text = '",char(splitVar(k)),"__",int2str(i),"';");
                                eval(str);
                            else
                                str=strcat(char(splitVar(k)),"= uitreenode(app.Tree);",char(splitVar(k)),".Text = '",char(splitVar(k)),"';");
                                eval(str);
                            end
                        elseif k==strDim(1,1)
                            str=strcat(char(splitVar(k)),"= uitreenode(app.Tree);",char(splitVar(k)),".Text = '",char(splitVar(k)),"__",int2str(i),"';");
                            eval(str);
                        end
                    elseif k==2
                        if ~any(strcmp(secondLayer,char(splitVar(k))))
                            secondLayer=[secondLayer,splitVar(k)];
                            if k==strDim(1,1)
                                str=strcat(char(splitVar(k)),"= uitreenode(",char(splitVar(k-1)),");",char(splitVar(k)),".Text = '",char(splitVar(k)),"__",int2str(i),"';");
                                eval(str);
                            else
                                str=strcat(char(splitVar(k)),"= uitreenode(",char(splitVar(k-1)),");",char(splitVar(k)),".Text = '",char(splitVar(k)),"';");
                                eval(str);
                            end
                        elseif k==strDim(1,1)
                            str=strcat(char(splitVar(k)),"= uitreenode(",char(splitVar(k-1)),");",char(splitVar(k)),".Text = '",char(splitVar(k)),"__",int2str(i),"';");
                            eval(str);
                        end
                    elseif k==3
                        if ~any(strcmp(thirdLayer,char(splitVar(k))))
                            thirdLayer=[thirdLayer,splitVar(k)];
                            if k==strDim(1,1)
                                str=strcat(char(splitVar(k)),"= uitreenode(",char(splitVar(k-1)),");",char(splitVar(k)),".Text = '",char(splitVar(k)),"__",int2str(i),"';");
                                eval(str);
                            else
                                str=strcat(char(splitVar(k)),"= uitreenode(",char(splitVar(k-1)),");",char(splitVar(k)),".Text = '",char(splitVar(k)),"';");
                                eval(str);
                            end
                        elseif k==strDim(1,1)
                            str=strcat(char(splitVar(k)),"= uitreenode(",char(splitVar(k-1)),");",char(splitVar(k)),".Text = '",char(splitVar(k)),"__",int2str(i),"';");
                            eval(str);
                        end
                    elseif k==4
                        if ~any(strcmp(fourthLayer,char(splitVar(k))))
                            fourthLayer=[fourthLayer,splitVar(k)];
                            if k==strDim(1,1)
                                str=strcat(char(splitVar(k)),"= uitreenode(",char(splitVar(k-1)),");",char(splitVar(k)),".Text = '",char(splitVar(k)),"__",int2str(i),"';");
                                eval(str);
                            else
                                str=strcat(char(splitVar(k)),"= uitreenode(",char(splitVar(k-1)),");",char(splitVar(k)),".Text = '",char(splitVar(k)),"';");
                                eval(str);
                            end
                        elseif k==strDim(1,1)
                            str=strcat(char(splitVar(k)),"= uitreenode(",char(splitVar(k-1)),");",char(splitVar(k)),".Text = '",char(splitVar(k)),"__",int2str(i),"';");
                            eval(str);
                        end
                    end
                end
            end
            firstLayer
            secondLayer
            thirdLayer
            fourthLayer
            splitVar
            %IMPORTANTE USARE NODEDATA PER SALVARE LE VARIABILI NEL NODO E
            %AGGIUNGERE TUTTO IN UNA STRUCT
        end

        % Callback function
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            switch selectedNodes.Text
                case 'Inverter right'
                
                case 'data1_r'
                    
                case 'data2_r'
                
                case 'Inverter left'
                    
                case 'data1_l'
                    
                case 'data2_l'
                    
                case 'Bms_hv'
                    
                case 'temperature'
                    
                case 'max_thv'
                    
                case 'min_thv'
                    
                case 'average_thv'
                    
                case 'voltage'
                    
                case 'max_vhv'
                    
                case 'min_vhv'
                    
                case 'average_vhv'
                    
                case 'current'
                    
                case 'current_c'
                    
                case 'pow'
                    
                case 'Bms_lv'
                    
                case 'voltage_lv'
                    
                case 'temperature_lv'
                    
                case 'Imu_gyro'
                    
                case 'x_g'
                
                case 'y_g'
                    
                case 'z_g'
                    
                case 'Imu_accel'
                    
                case 'x_a'
                    
                case 'y_a'
                    
                case 'z_a'
                    
                case 'Front_wheels_encoder'
                    
                case 'speed'
                
                case 'speedms'
                    
                case 'Steering_wheel_encoder'
                    
                case 'Distance'
                    
                case 'meters'
                    
                case 'rotation'
                    
                case 'angle' 
                   
                case 'clock_period'
                    
                case 'Throttle'
                    if app.Switch.Value=="Off"
                        cla(app.UIAxes);
                        plot(app.UIAxes,app.tblThrottle{:,5})
                    else
                        sizeT=size(app.tblThrottle);
                        app.overlay_n=[app.overlay_n,"Throttle"];
                        app.overlay_l=[app.overlay_l;sizeT(1,1)];
                        overlayed(app);
                    end
                case 'Brake' 
                    if app.Switch.Value=="Off"
                        cla(app.UIAxes);
                        plot(app.UIAxes,app.tblBrake{:,5})
                    else
                        sizeT=size(app.tblBrake);
                        app.overlay_n=[app.overlay_n,"Brake"];
                        app.overlay_l=[app.overlay_l;sizeT(1,1)];
                        overlayed(app);
                    end
            end
        end

        % Button pushed function: PulisciassiButton
        function PulisciassiButtonPushed(app, event)
            cla(app.UIAxes);
            app.overlay=[];
        end

        % Button pushed function: FiltraButton_2
        function FiltraButton_2Pushed(app, event)
            app.FiltraButton_2.Enable=false;
            minMark=min(app.control(:,1));
            maxMark=max(app.control(:,1));
            minTime=min(app.control(:,2));
            maxTime=max(app.control(:,2));
            
            app.filterApp = filtra(app,minMark,maxMark,minTime,maxTime); 

        end

        % Close request function: GraficoUIFigure
        function GraficoUIFigureCloseRequest(app, event)
            delete(app.filterApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GraficoUIFigure = uifigure('Visible', 'off');
            app.GraficoUIFigure.Name = 'Grafico';
            app.GraficoUIFigure.CloseRequestFcn = createCallbackFcn(app, @GraficoUIFigureCloseRequest, true);
            app.GridLayout2 = uigridlayout(app.GraficoUIFigure);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PulisciassiButton = uibutton(app.GridLayout2, 'push');
            app.PulisciassiButton.ButtonPushedFcn = createCallbackFcn(app, @PulisciassiButtonPushed, true);
            app.PulisciassiButton.Text = 'Pulisci assi';
            app.Switch = uiswitch(app.GridLayout2, 'slider');
            app.SOVRAPPOSIZIONELabel = uilabel(app.GridLayout2);
            app.SOVRAPPOSIZIONELabel.Text = 'SOVRAPPOSIZIONE';
            app.FiltraButton_2 = uibutton(app.GridLayout2, 'push');
            app.FiltraButton_2.ButtonPushedFcn = createCallbackFcn(app, @FiltraButton_2Pushed, true);
            app.FiltraButton_2.Text = 'Filtra';
            app.Tree = uitree(app.GridLayout2);
            app.TempodiavvioLabel = uilabel(app.GraficoUIFigure);
            app.TempodiavvioLabel.Text = 'Tempo di avvio:';
            % Show the figure after all components are created
            app.GraficoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = grafico(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GraficoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GraficoUIFigure)
        end
    end
end